classdef algorithmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        InitialValuesTab         matlab.ui.container.Tab
        BandwidthSpinnerLabel    matlab.ui.control.Label
        BandwidthSpinner         matlab.ui.control.Spinner
        ChannelGainSpinnerLabel  matlab.ui.control.Label
        ChannelGainSpinner       matlab.ui.control.Spinner
        NoisePowerSpinnerLabel   matlab.ui.control.Label
        NoisePowerSpinner        matlab.ui.control.Spinner
        VelocitySpinnerLabel     matlab.ui.control.Label
        VelocitySpinner          matlab.ui.control.Spinner
        HeightSpinnerLabel       matlab.ui.control.Label
        HeightSpinner            matlab.ui.control.Spinner
        UITable                  matlab.ui.control.Table
        yLabel                   matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        xLabel                   matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        AddSensorNodeLabel       matlab.ui.control.Label
        CoordinatesLabel         matlab.ui.control.Label
        AddButton                matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        PowerSpinnerLabel        matlab.ui.control.Label
        PowerSpinner             matlab.ui.control.Spinner
        LoadButton               matlab.ui.control.Button
        CenterButton             matlab.ui.control.Button
        DeleteAllButton          matlab.ui.control.Button
        DeleteLastButton         matlab.ui.control.Button
        PackageLengthLabel       matlab.ui.control.Label
        LLabel                   matlab.ui.control.Label
        LEditField               matlab.ui.control.NumericEditField
        ShortestPathTab          matlab.ui.container.Tab
        AxesPath1                matlab.ui.control.UIAxes
        RunPathButton            matlab.ui.control.Button
        AxesPath2                matlab.ui.control.UIAxes
        SumRateOptimizationTab   matlab.ui.container.Tab
        RunSumButton             matlab.ui.control.Button
        AxesSum1                 matlab.ui.control.UIAxes
        AxesSum2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        snArray=[];
        snItem = [];
        snCenter = [];
        itemNum = 1;
        deleteLast = 0;
        P = 1;
        v = 1;
        h = 100;
        beta = 10^-4;
        sigma = 10^-7;
        B = 10^5;
        L = [];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Value changed function: BandwidthSpinner
        function BandwidthSpinnerValueChanged(app, event)
            app.B = app.BandwidthSpinner.Value;
            
        end

        % Value changed function: ChannelGainSpinner
        function ChannelGainSpinnerValueChanged(app, event)
            app.beta = app.ChannelGainSpinner.Value;
            
        end

        % Value changed function: NoisePowerSpinner
        function NoisePowerSpinnerValueChanged(app, event)
            app.sigma = app.NoisePowerSpinner.Value;
            
        end

        % Value changed function: VelocitySpinner
        function VelocitySpinnerValueChanged(app, event)
            app.v = app.VelocitySpinner.Value;
            
        end

        % Value changed function: HeightSpinner
        function HeightSpinnerValueChanged(app, event)
            app.h = app.HeightSpinner.Value;
            
        end

        % Value changed function: PowerSpinner
        function PowerSpinnerValueChanged(app, event)
            app.P = app.PowerSpinner.Value;
            
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if(app.deleteLast == 1)
                app.deleteLast = 0;
                app.snArray = app.snArray(1:end-1,:); 
                app.L = app.L(1:end-1); 
            else
                app.snItem = [app.xEditField.Value app.yEditField.Value];
                app.snArray = [app.snArray; app.snItem]; 
                app.L = [app.L app.LEditField.Value];
            end

            tdata = table(app.snArray(:,1),app.snArray(:,2),transpose(app.L),'VariableNames',{'x','y','L'});
            app.UITable.Data = tdata;
            cla(app.UIAxes2,'reset');
            if(~isempty(app.snArray))
                scatter(app.UIAxes2,app.snArray(:,1),app.snArray(:,2),"blue");       
                if(~isempty(app.snCenter))
                    hold(app.UIAxes2);
                    scatter(app.UIAxes2,app.snCenter(1,1),app.snCenter(1,2),'red');
                    axis(app.UIAxes2,[min([app.snArray(:,1); app.snCenter(1)])-10 max([app.snArray(:,1); app.snCenter(1)])+ 10 ...
                    min([app.snArray(:,2) ;app.snCenter(2)])-10 max([app.snArray(:,2); app.snCenter(2)])+10])
                else
                    axis(app.UIAxes2,[min(app.snArray(:,1))-10 max(app.snArray(:,1))+ 10 min(app.snArray(:,2))-10 max(app.snArray(:,2))+10])                 
                end

            end
            grid(app.UIAxes2, 'on'); 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cla(app.UIAxes2,'reset');
            [file,path] = uigetfile('*.txt');   
            T = readtable([path file]);
            app.UITable.Data = T(1:end-1,:);
            locationsArr = table2array(T);
            center = locationsArr(end,1:end-1);
            locations = locationsArr(1:end-1,1:end-1);
            packageLengths = locationsArr(1:end-1,end);
            app.L = transpose(packageLengths);
            app.snCenter = center;
            app.snArray = locations;
            scatter(app.UIAxes2,locations(:,1),locations(:,2),"blue");
            hold(app.UIAxes2);
            scatter(app.UIAxes2,center(1,1),center(1,2),'red');
            axis(app.UIAxes2,[min(locations(:,1))-10 max(locations(:,1))+10 min(locations(:,2))-10 max(locations(:,2))+10])
            grid(app.UIAxes2, 'on'); 
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            app.snCenter = [app.xEditField.Value app.yEditField.Value];
            hold(app.UIAxes2,"on");
            scatter(app.UIAxes2,app.snCenter(1,1),app.snCenter(1,2),'red');
            if(~isempty(app.snArray))     
                if(~isempty(app.snCenter))
                    scatter(app.UIAxes2,app.snCenter(1,1),app.snCenter(1,2),'red');
                    axis(app.UIAxes2,[min([app.snArray(:,1); app.snCenter(1)])-10 max([app.snArray(:,1); app.snCenter(1)])+ 10 ...
                    min([app.snArray(:,2) ;app.snCenter(2)])-10 max([app.snArray(:,2); app.snCenter(2)])+10])
                else
                    axis(app.UIAxes2,[min(app.snArray(:,1))-10 max(app.snArray(:,1))+ 10 min(app.snArray(:,2))-10 max(app.snArray(:,2))+10])                 
                end
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.UITable.Data = [];
            app.snArray = [];
            cla(app.UIAxes2,'reset');            
        end

        % Button pushed function: DeleteLastButton
        function DeleteLastButtonPushed(app, event)
            app.deleteLast = 1;
            app.AddButtonPushed;
        end

        % Button pushed function: RunPathButton
        function RunPathButtonPushed(app, event)
            iL = 0;
            constFlight = 1000000;
            u = app.snArray;
            lineSeq = perms(1:size(u,1)/2);
            while ~(iL == length(lineSeq))
                u = app.snArray;
                trx = [];
                u_temp = [];
                iL = iL + 1;
                center = app.snCenter;
                
                qt=1;
                for i = 1:2:length(u)
                    u_x(qt,:) = [u(i,:) u(i+1,:)];qt = qt+1;
                end
                lineSeqTemp = lineSeq(iL,:);
                for i = 1:length(lineSeqTemp)
                    uLine(i,:) =  u_x(lineSeqTemp(i),:);
                end
                
                [u_temp,trx] = lineSegmentPoint(uLine,app.L,app.B,app.beta,app.P,app.sigma,app.h);
                u_lineSegment = u_temp;
                
%                 coordinate = u;
%                 coordinate_comp = u_temp;
%                 xaxis_comp = coordinate_comp(:,1);
%                 yaxis_comp = coordinate_comp(:,2);
%                 xaxis = transpose(coordinate(:,1));
%                 yaxis = transpose(coordinate(:,2));
%                 plot (xaxis,yaxis,'rO','LineWidth',2);
%                 hold on
%                 plot (xaxis_comp,yaxis_comp,'bO','LineWidth',2);
%                 hold on
%                 plot(center(1),center(2),'gO','LineWidth',2);
%                 axis([min([center xaxis])-1 max([center xaxis])+1 min([center yaxis])-1 max([center yaxis])+1]);
%                 grid on
                
                [costFunc,path] = pathFinder(u_temp,center,trx,app.v);
                agesLine(iL,:) = costFunc;
                pathLine(iL,:) = path;
                
                for i = 1:length(path)-1
                    if i == length(path)-1
                        flightN2N(iL,i)=dstnc(u(path(i),:),center)*app.v^-1;
                    else
                        flightN2N(iL,i)=dstnc(u(path(i),:),u(path(i+1),:))*app.v^-1;
                    end                
                    flightTime(iL,1) = sum(flightN2N(iL,:));
                    pathLineX(iL,i) = u(path(i),1);
                    pathLineY(iL,i) = u(path(i),2);
                end
               if constFlight > flightTime(iL)               
                choosenPath = iL;
                agesL(1,:) = agesLine(choosenPath,:);
                path(1,:) = pathLine(choosenPath,:);
                    
                for i = 1:length(path(1:end-1))
                   ss{1,i} = [num2str(i),' (',num2str(u(path(i),:)),')'];  
                end
               end
            end               
%                 figure;
%                 bar(costFunc(1,:))
%                 set(gca,'xticklabel',ss(method,:))
%                 figure;           
            ageLL(1,:)=agesLine(57,:);
            ages = [agesL;ageLL];
            
            bar(app.AxesPath2,transpose(ages))
            legend(app.AxesPath2,'LineSegment Optimization Points','LineSegment Optimization Points2')
            title(app.AxesPath2,'ages')
            
          
            coordinate = u;
            coordinate_comp = u_lineSegment;
            xaxis_comp = coordinate_comp(:,1);
            yaxis_comp = coordinate_comp(:,2);
            xaxis = transpose(coordinate(:,1));
            yaxis = transpose(coordinate(:,2));
            plot(app.AxesPath1,xaxis,yaxis,'gO','LineWidth',2);
            hold(app.AxesPath1)
            for i = 1:2:length(xaxis)          
            plot(app.AxesPath1,[xaxis(i);xaxis(i+1)],[yaxis(i);yaxis(i+1)],'g-')             
            end
            
            for i = 1:length(xaxis_comp)-1
            plot(app.AxesPath1,[xaxis_comp(i);xaxis_comp(i+1)],[yaxis_comp(i);yaxis_comp(i+1)],'b--')             
            end
            
            plot (app.AxesPath1,xaxis_comp,yaxis_comp,'bO','LineWidth',2);
            plot(app.AxesPath1,center(1),center(2),'rO','LineWidth',2);
            axis(app.AxesPath1,[min([center xaxis])-10 max([center xaxis])+10 min([center yaxis])-10 max([center yaxis])+10]);
            title(app.AxesPath1,'Line Segment Points')
            grid(app.AxesPath1, 'on')


        end

        % Button pushed function: RunSumButton
        function RunSumButtonPushed(app, event)
            global L1_nlc
            global L2_nlc
            global h_nlc
            global P_nlc
            global d_nlc
            u = app.snArray;
            k1 = (app.beta*app.P)/app.sigma;
            k2 = (app.beta*app.P)/app.sigma;
            R1ub = app.B*log2(1+k1/(app.h^2));
            R2ub = app.B*log2(1+k2/(app.h^2));
            options = optimoptions('fmincon','Display','off');%,'Algorithm','sqp');
            syms x Y q1 q2
            xt = [x Y q1 q2];
            func = @(x)x(2);
            nonlcon = @nlc;
            x0 = [0 0 0 0];
            A = []; % No other constraints
            b = [];
            Aeq = [];
            beq = [];
            lb = [0 0 0 0];
            h_nlc = app.h;
            P_nlc = app.P;
            trx = [];
            q = 1;
            center = app.snCenter;
            for i = 1:2:length(u)
                point1 = [u(i,1) u(i,2)];
                point2 = [u(i+1,1) u(i+1,2)];
                d_nlc = dstnc(point1,point2);
                ub = [d_nlc R1ub+R2ub R1ub R2ub]; 
                L1_nlc = app.L(i);
                L2_nlc = app.L(i+1);
        %         [x1,y1,txComp,r1,r2,choosenLambda] = completionPoint(point1,point2,L(i),L(i+1),B,beta,P,sigma,h);
        %         close Figure 1
                sol = fmincon(@(xt) func(xt),x0,A,b,Aeq,beq,lb,ub,nonlcon,options);
                fprintf('x > %f , q1 > %f , q2 > %f\n',sol(1),sol(3),sol(4));
                x11 = point1(1)*(1-sol(1)/d_nlc) + point2(1)*sol(1)/d_nlc;
                y11 = point1(2)*(1-sol(1)/d_nlc) + point2(2)*sol(1)/d_nlc;
                txnew1 = app.L(i)/sol(3);
                txnew2 = app.L(i+1)/sol(4);
                txnew = max(txnew1,txnew2);
        %         fprintf('xErr(%%): %f , yErr(%%): %f , timeErr(%%): %f\n',abs(x1-x11)/max(x1,x11),abs(y1-y11)/max(y1,y11),abs(txComp-txnew)/max(txComp,txnew));
        %         syms y
        %         R11(y) = B*log2(1+k1/(h^2+y^2 ));
        %         R22(y) = B*log2(1+k2/(h^2+(d-y)^2));
        %         C(y) = B*log2(1+ k1/(h^2+y^2)+k2/(h^2+(d-y)^2));
        %         double(R11(sol(1)));
        %         double(R22(sol(1)));
        %         double(C(sol(1)));
        %         figure
        %         plotter(double(R11(sol(1))),double(R22(sol(1))),...
        %             double(C(sol(1))),L2/L1,sol(3),sol(4),sol(1),d);
                trx = [trx txnew];
                u_temp(q,1) = x11;
                u_temp(q,2) = y11;
                q = q + 1;     
            end
            u_completionTime = u_temp;
            tx_completionTime = trx;
            
            [costFuncSum,pathSum] = pathFinder(u_temp,center,trx,app.v);
            agesComp(1,:) = costFuncSum;
            pathComp(1,:) = pathSum;
              
            bar(app.AxesSum2,transpose(agesComp))
            legend(app.AxesSum2,'Completion Time Points')
            title(app.AxesSum2,'ages')
            
            coordinate = u;
            coordinate_comp = u_completionTime;
            xaxis_comp = coordinate_comp(:,1);
            yaxis_comp = coordinate_comp(:,2);
            xaxis = transpose(coordinate(:,1));
            yaxis = transpose(coordinate(:,2));
            plot(app.AxesSum1,xaxis,yaxis,'gO','LineWidth',2);
            hold(app.AxesSum1)
            for i = 1:2:length(xaxis)          
            plot(app.AxesSum1,[xaxis(i);xaxis(i+1)],[yaxis(i);yaxis(i+1)],'g-')             
            end
            
            for i = 1:length(xaxis_comp)-1
            plot(app.AxesSum1,[xaxis_comp(i);xaxis_comp(i+1)],[yaxis_comp(i);yaxis_comp(i+1)],'b--')             
            end
            
            plot (app.AxesSum1,xaxis_comp,yaxis_comp,'bO','LineWidth',2);
            plot(app.AxesSum1,center(1),center(2),'rO','LineWidth',2);
            axis(app.AxesSum1,[min([center xaxis])-10 max([center xaxis])+10 min([center yaxis])-10 max([center yaxis])+10]);
            title(app.AxesSum1,'Completion Segment Points')
            grid(app.AxesSum1, 'on')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitialValuesTab = uitab(app.TabGroup);
            app.InitialValuesTab.Title = 'Initial Values';
            app.BandwidthSpinnerLabel = uilabel(app.InitialValuesTab);
            app.BandwidthSpinnerLabel.Text = 'Bandwidth';
            app.BandwidthSpinner = uispinner(app.InitialValuesTab);
            app.BandwidthSpinner.ValueChangedFcn = createCallbackFcn(app, @BandwidthSpinnerValueChanged, true);
            app.BandwidthSpinner.Value = 1000000;
            app.ChannelGainSpinnerLabel = uilabel(app.InitialValuesTab);
            app.ChannelGainSpinnerLabel.Text = 'Channel Gain';
            app.ChannelGainSpinner = uispinner(app.InitialValuesTab);
            app.ChannelGainSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelGainSpinnerValueChanged, true);
            app.ChannelGainSpinner.Value = 0.001;
            app.NoisePowerSpinnerLabel = uilabel(app.InitialValuesTab);
            app.NoisePowerSpinnerLabel.Text = 'Noise Power';
            app.NoisePowerSpinner = uispinner(app.InitialValuesTab);
            app.NoisePowerSpinner.ValueChangedFcn = createCallbackFcn(app, @NoisePowerSpinnerValueChanged, true);
            app.NoisePowerSpinner.Value = 1e-06;
            app.VelocitySpinnerLabel = uilabel(app.InitialValuesTab);
            app.VelocitySpinnerLabel.Text = 'Velocity';
            app.VelocitySpinner = uispinner(app.InitialValuesTab);
            app.VelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitySpinnerValueChanged, true);
            app.VelocitySpinner.Value = 1;
            app.HeightSpinnerLabel = uilabel(app.InitialValuesTab);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.InitialValuesTab);
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @HeightSpinnerValueChanged, true);
            app.HeightSpinner.Value = 100;
            app.UITable = uitable(app.InitialValuesTab);
            app.UITable.ColumnName = {'x'; 'y'; 'L'};
            app.UITable.RowName = {''};
            app.yLabel = uilabel(app.InitialValuesTab);
            app.yLabel.Text = 'y:';
            app.yEditField = uieditfield(app.InitialValuesTab, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.xLabel = uilabel(app.InitialValuesTab);
            app.xLabel.Text = 'x:';
            app.xEditField = uieditfield(app.InitialValuesTab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.AddSensorNodeLabel = uilabel(app.InitialValuesTab);
            app.AddSensorNodeLabel.Text = 'Add Sensor Node';
            app.CoordinatesLabel = uilabel(app.InitialValuesTab);
            app.CoordinatesLabel.Text = 'Coordinates:';
            app.AddButton = uibutton(app.InitialValuesTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UIAxes2 = uiaxes(app.InitialValuesTab);
            title(app.UIAxes2, 'Sensor Network')
            xlabel(app.UIAxes2, 'X Coordinates')
            ylabel(app.UIAxes2, 'Y coordinates')
            app.PowerSpinnerLabel = uilabel(app.InitialValuesTab);
            app.PowerSpinnerLabel.Text = 'Power';
            app.PowerSpinner = uispinner(app.InitialValuesTab);
            app.PowerSpinner.ValueChangedFcn = createCallbackFcn(app, @PowerSpinnerValueChanged, true);
            app.PowerSpinner.Value = 1;
            app.LoadButton = uibutton(app.InitialValuesTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CenterButton = uibutton(app.InitialValuesTab, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.DeleteAllButton = uibutton(app.InitialValuesTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.DeleteLastButton = uibutton(app.InitialValuesTab, 'push');
            app.DeleteLastButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastButtonPushed, true);
            app.DeleteLastButton.Text = 'Delete Last';
            app.PackageLengthLabel = uilabel(app.InitialValuesTab);
            app.PackageLengthLabel.Text = 'Package Length:';
            app.LLabel = uilabel(app.InitialValuesTab);
            app.LLabel.Text = 'L:';
            app.LEditField = uieditfield(app.InitialValuesTab, 'numeric');
            app.ShortestPathTab = uitab(app.TabGroup);
            app.ShortestPathTab.Title = 'Shortest Path';
            app.AxesPath1 = uiaxes(app.ShortestPathTab);
            title(app.AxesPath1, 'Title')
            xlabel(app.AxesPath1, 'X')
            ylabel(app.AxesPath1, 'Y')
            app.RunPathButton = uibutton(app.ShortestPathTab, 'push');
            app.RunPathButton.ButtonPushedFcn = createCallbackFcn(app, @RunPathButtonPushed, true);
            app.RunPathButton.Text = 'Run';
            app.AxesPath2 = uiaxes(app.ShortestPathTab);
            title(app.AxesPath2, 'Title')
            xlabel(app.AxesPath2, 'X')
            ylabel(app.AxesPath2, 'Y')
            app.SumRateOptimizationTab = uitab(app.TabGroup);
            app.SumRateOptimizationTab.Title = 'Sum Rate Optimization';
            app.RunSumButton = uibutton(app.SumRateOptimizationTab, 'push');
            app.RunSumButton.ButtonPushedFcn = createCallbackFcn(app, @RunSumButtonPushed, true);
            app.RunSumButton.Text = 'Run';
            app.AxesSum1 = uiaxes(app.SumRateOptimizationTab);
            title(app.AxesSum1, 'Title')
            xlabel(app.AxesSum1, 'X')
            ylabel(app.AxesSum1, 'Y')
            app.AxesSum2 = uiaxes(app.SumRateOptimizationTab);
            title(app.AxesSum2, 'Title')
            xlabel(app.AxesSum2, 'X')
            ylabel(app.AxesSum2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algorithmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end